classdef testing_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Lamp               matlab.ui.control.Lamp
        TESTButton         matlab.ui.control.Button
        ShowpyenvButton    matlab.ui.control.Button
        ChangepyenvButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChangepyenvButton
        function ChangepyenvButtonPushed(app, event)
            terminate(pyenv)

            % Prompt the user to select a new Python executable
            [file, path] = uigetfile({'python.exe'}, 'Select Python Executable');
            if isequal(file, 0) || isequal(path, 0)
                disp('Operation cancelled. Python executable not changed.');
            else
                pythonExecutablePath = fullfile(path, file);
                disp(['Python environment will be updated to use: ', pythonExecutablePath]);
                pyenv(Version=pythonExecutablePath)
                pyenv(ExecutionMode="OutOfProcess")
                pyenv
            end
        end

        % Button pushed function: ShowpyenvButton
        function ShowpyenvButtonPushed(app, event)
            pyenv
        end

        % Button pushed function: TESTButton
        function TESTButtonPushed(app, event)
            app.Lamp.Color = [1 1 1];
            drawnow

            testFile = fullfile("example_mesh_data.mat");   % packed with the app
            loadedData = load(testFile); 
            mesh_data = loadedData.mesh_data;

            try
                output = mesh_subtract_testing_python(mesh_data);
                app.Lamp.Color = [0 1 0];
                disp(output)
                disp('after output')
                disp('THE PYTHON SCRIPT RAN SUCCESSFULLY')
            catch e
                e.message
                if(isa(e,'matlab.exception.PyException'))
                    e.ExceptionObject
                end
                app.Lamp.Color = [1 0 0];
                disp('THE PYTHON SCRIPT FAILED')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChangepyenvButton = uibutton(app.UIFigure, 'push');
            app.ChangepyenvButton.ButtonPushedFcn = createCallbackFcn(app, @ChangepyenvButtonPushed, true);
            app.ChangepyenvButton.Tooltip = {'Press to set up the python environment. '};
            app.ChangepyenvButton.Text = 'Change pyenv';
            app.ShowpyenvButton = uibutton(app.UIFigure, 'push');
            app.ShowpyenvButton.ButtonPushedFcn = createCallbackFcn(app, @ShowpyenvButtonPushed, true);
            app.ShowpyenvButton.Tooltip = {'Show the currently used Python environment in the terminal.'};
            app.ShowpyenvButton.Text = 'Show pyenv';
            app.TESTButton = uibutton(app.UIFigure, 'push');
            app.TESTButton.ButtonPushedFcn = createCallbackFcn(app, @TESTButtonPushed, true);
            app.TESTButton.Tooltip = {'Run the Python script to test it - results will show up in the terminal.'};
            app.TESTButton.Text = 'TEST';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Tooltip = {'Indicates whether the Python script ran successfully or not.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testing_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end